classdef AnimatorControls < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        TimeSlider             matlab.ui.control.Slider
        LabelNumericEditField  matlab.ui.control.Label
        TimeField              matlab.ui.control.NumericEditField
        LabelDropDown          matlab.ui.control.Label
        POVDropDown            matlab.ui.control.DropDown
        PlayButton             matlab.ui.control.StateButton
        LoopButton             matlab.ui.control.StateButton
        BackButton             matlab.ui.control.Button
        Button                 matlab.ui.control.Button
    end


    properties (Access = public)
        anim = 0;
        TimeStepListener = 0;
        Playlistener = 0;
        Closelistener = 0;
    end
    
    methods
        function set.anim(app, anim)
            
            app.TimeStepListener = addlistener(anim, 'newTimeStep', @(s, e) app.ListenToNewTimeStep(s, e));
            app.Playlistener = addlistener(anim, 'playStateChanged', @(s, e) app.ListenToPlayStateChanged(s, e));
            %app.Closelistener = addlistener(anim, 'animDeleting', @(s, e) app.ListenToClosing(s, e));
            
            app.anim = anim;
            app.TimeSlider.Limits = [anim.startTime, anim.endTime];
            app.TimeSlider.MajorTicks = [anim.startTime, anim.endTime];
            
            app.PlayButton.Value = anim.isPlaying;
            app.LoopButton.Value = anim.isLooping;
            
            app.POVDropDown.Value = frost.Animator.AnimatorControls.POVToText(anim.pov);
        end
        
        function ListenToNewTimeStep(app, src, eventData)
            app.TimeSlider.Value = eventData.t;
            app.TimeField.Value = eventData.t;
        end
        
        function ListenToPlayStateChanged(app, src, eventData)
            app.PlayButton.Value = app.anim.isPlaying;
        end
        
        function ListenToClosing(app, src, eventData)
            disp('WOW');
            delete(app);
        end
    end

    methods (Access = public, Static)
        function pov = TextToPOV(text)
            switch(text)
                case 'North'
                    pov = frost.Animator.AnimatorPointOfView.North;
                case 'South'
                    pov = frost.Animator.AnimatorPointOfView.South;
                case 'East'
                    pov = frost.Animator.AnimatorPointOfView.East;
                case 'West'
                    pov = frost.Animator.AnimatorPointOfView.West;
                case 'Front'
                    pov = frost.Animator.AnimatorPointOfView.Front;
                case 'Back'
                    pov = frost.Animator.AnimatorPointOfView.Back;
                case 'Left'
                    pov = frost.Animator.AnimatorPointOfView.Left;
                case 'Right'
                    pov = frost.Animator.AnimatorPointOfView.Right;
                case 'Top Front Left'
                    pov = frost.Animator.AnimatorPointOfView.TopFrontLeft;
                case 'Top South East'
                    pov = frost.Animator.AnimatorPointOfView.TopSouthEast;
                otherwise
                    pov = frost.Animator.AnimatorPointOfView.Free;
            end
        end
        
        function text = POVToText(pov)
            switch(pov)
                case frost.Animator.AnimatorPointOfView.North
                    text = 'North';
                case frost.Animator.AnimatorPointOfView.South
                    text = 'South';
                case frost.Animator.AnimatorPointOfView.East
                    text = 'East';
                case frost.Animator.AnimatorPointOfView.West
                    text = 'West';
                case frost.Animator.AnimatorPointOfView.Front
                    text = 'Front';
                case frost.Animator.AnimatorPointOfView.Back
                    text = 'Back';
                case frost.Animator.AnimatorPointOfView.Left
                    text = 'Left';
                case frost.Animator.AnimatorPointOfView.Right
                    text = 'Right';
                case frost.Animator.AnimatorPointOfView.TopFrontLeft
                    text = 'Top Front Left';
                case frost.Animator.AnimatorPointOfView.TopSouthEast
                    text = 'Top South East';
                case frost.Animator.AnimatorPointOfView.Free
                    text = 'Free';
            end
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Value changed function: TimeSlider
        function TimeSliderChanged(app, event)
            if isnumeric(app.anim)
                return;
            end
            
            value = app.TimeSlider.Value;
            
            app.anim.currentTime = value;
            app.anim.Animate(true);
        end

        % Value changed function: TimeField
        function TimeFieldChanged(app, event)
            if isnumeric(app.anim)
                return;
            end
            
            value = app.TimeField.Value;
            
            app.anim.currentTime = value;
            app.anim.Animate(true);
        end

        % Value changed function: POVDropDown
        function POVChanged(app, event)
            if isnumeric(app.anim)
                return;
            end
            
            value = app.POVDropDown.Value;
            
            app.anim.pov = frost.Animator.AnimatorControls.TextToPOV(value);
            app.anim.Animate(true);
        end

        % Value changed function: PlayButton
        function PlayChanged(app, event)
            if isnumeric(app.anim)
                return;
            end
            
            value = app.PlayButton.Value;
            
            app.anim.isPlaying = value;
        end

        % Value changed function: LoopButton
        function LoopChanged(app, event)
            if isnumeric(app.anim)
                return;
            end
            
            value = app.LoopButton.Value;
            
            app.anim.isLooping = value;
        end

        % Value changing function: TimeSlider
        function TimeSliderChanging(app, event)
            if isnumeric(app.anim)
                return;
            end
            
            value = event.Value;
            
            app.anim.currentTime = value;
            app.anim.Animate(true);
        end

        % Button pushed function: Button
        function SkipForward(app, event)
            if isnumeric(app.anim)
                return;
            end
            
            app.anim.currentTime = app.anim.currentTime + 0.05;
            app.anim.Animate(true);
        end

        % Button pushed function: BackButton
        function SkipBackward(app, event)
            if isnumeric(app.anim)
                return;
            end
            
            app.anim.currentTime = app.anim.currentTime - 0.05;
            app.anim.Animate(true);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.TimeSlider = uislider(app.UIFigure);
            app.TimeSlider.MajorTicks = [0 100];
            app.TimeSlider.ValueChangedFcn = createCallbackFcn(app, @TimeSliderChanged, true);
            app.TimeSlider.ValueChangingFcn = createCallbackFcn(app, @TimeSliderChanging, true);
            app.TimeSlider.MinorTicks = [];
            app.LabelNumericEditField = uilabel(app.UIFigure);
            app.LabelNumericEditField.Text = 'Time';
            app.TimeField = uieditfield(app.UIFigure, 'numeric');
            app.TimeField.ValueChangedFcn = createCallbackFcn(app, @TimeFieldChanged, true);
            app.TimeField.ValueDisplayFormat = '%04.03f';
            app.LabelDropDown = uilabel(app.UIFigure);
            app.LabelDropDown.Text = 'Drop Down';
            app.POVDropDown = uidropdown(app.UIFigure);
            app.POVDropDown.Items = {'North', 'South', 'East', 'West', 'Front', 'Back', 'Left', 'Right', 'Top Front Left', 'Top South East', 'Free'};
            app.POVDropDown.Editable = 'on';
            app.POVDropDown.ValueChangedFcn = createCallbackFcn(app, @POVChanged, true);
            app.POVDropDown.Value = 'Top Front Left';
            app.PlayButton = uibutton(app.UIFigure, 'state');
            app.PlayButton.ValueChangedFcn = createCallbackFcn(app, @PlayChanged, true);
            app.PlayButton.Text = 'Play';
            app.LoopButton = uibutton(app.UIFigure, 'state');
            app.LoopButton.ValueChangedFcn = createCallbackFcn(app, @LoopChanged, true);
            app.LoopButton.Text = 'Loop';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @SkipBackward, true);
            app.BackButton.Text = '<< Backward';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @SkipForward, true);
            app.Button.Text = 'Forward >>';
        end
    end

    methods (Access = public)

        % Construct app
        function app = AnimatorControls()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end